classdef MLA_GUI_Tab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SeethePriceinLeiButton      matlab.ui.control.StateButton
        ThePredictedPriceEditField  matlab.ui.control.NumericEditField
        ThePredictedPriceLabel      matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        PriceEvaluationAppLabel     matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, userBrand)
            
        end

        % Value changed function: ThePredictedPriceEditField
        function ThePredictedPriceEditFieldValueChanged(app, event)

        end

        % Value changed function: SeethePriceinLeiButton
        function SeethePriceinLeiButtonValueChanged(app, event)
        
            if app.SeethePriceinLeiButton.Text == "See the Price in Lei"
                app.ThePredictedPriceEditField.Value = app.ThePredictedPriceEditField.Value * 4.98;
                app.SeethePriceinLeiButton.Text = 'See the Price in Euro';
            else
                app.SeethePriceinLeiButton.Text = 'See the Price in Lei';
                format short
                app.ThePredictedPriceEditField.Value = app.ThePredictedPriceEditField.Value / 4.98;
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Open the first tab 
                    seconTab = app;  
                    firstTab = MLA_GUI; % Create an instance of the first tab class
                    firstTab.UIFigure.Visible = 'on'; % Make the first tab visible
                    seconTab.UIFigure.Visible = 'off';  % Make the second tab invisible
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cars.jpeg');
            app.PriceEvaluationAppLabel = uilabel(app.UIFigure);
            app.PriceEvaluationAppLabel.Text = 'Price Evaluation App';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ThePredictedPriceLabel = uilabel(app.UIFigure);
            app.ThePredictedPriceLabel.Text = 'The Predicted Price: ';
            app.ThePredictedPriceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThePredictedPriceEditField.ValueChangedFcn = createCallbackFcn(app, @ThePredictedPriceEditFieldValueChanged, true);
            app.SeethePriceinLeiButton = uibutton(app.UIFigure, 'state');
            app.SeethePriceinLeiButton.ValueChangedFcn = createCallbackFcn(app, @SeethePriceinLeiButtonValueChanged, true);
            app.SeethePriceinLeiButton.Text = 'See the Price in Lei';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MLA_GUI_Tab2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end